classdef Motor_spindown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        StartButton       matlab.ui.control.Button
        MotorButtonGroup  matlab.ui.container.ButtonGroup
        CButton           matlab.ui.control.ToggleButton
        BButton           matlab.ui.control.ToggleButton
        AButton           matlab.ui.control.ToggleButton
        Switch            matlab.ui.control.Switch
        ViscousDampingConstantEditFieldLabel  matlab.ui.control.Label
        ViscousDampingConstantEditField  matlab.ui.control.NumericEditField
        TorquefromCoulombFrictionEditField  matlab.ui.control.NumericEditField
        TorquefromCoulombFrictionEditFieldLabel  matlab.ui.control.Label
        UIAxes2           matlab.ui.control.UIAxes
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function results = Motor_Dynamics(app)
        %%%% motor motion Written by Max Austin... in a a day ... with no experience     
        
            %%%% sets up the motor properties
            omeganot = 64; % rad/sec
            ThetaNot = 0;
            Props.Tstall = 0;
            Props.Omegnoload = 64;
            Props.Inert = 1; %kg*m^2
            Time = 0:.0005:6;
            sets4ode = odeset('AbsTol',1e-10);
            
            %%%% Define the motor Properties
            switch(app.Switch.Value)
                case 'Assignment 1'
                    Props.c = 0;
                    Props.b = app.ViscousDampingConstantEditField.Value;
                    Props.Tf = app.TorquefromCoulombFrictionEditField.Value;
                case 'Assignment 2'
                    if(app.AButton.Value == true)
                        Props.c = 0;
                        Props.b = 0.00001;
                        Props.Tf = 9;
                    elseif(app.BButton.Value == true)
                        Props.c = 0;
                        Props.b = 1.2;
                        Props.Tf = 0;
                    else
                        Props.c = 0;
                        Props.b = 0.75;
                        Props.Tf = 7;
                    end
            end
            
            if ((Props.b > 0 ) && (Props.b <= 0.5) && (Props.Tf < 10))
                Time = 0:.005:10;
            end
            app.StartButton.BackgroundColor = [0.3922 0.8314 0.0745];
            
           [T,Omega] =  ode45(@app.Motor_EOM,Time,[ThetaNot,omeganot],sets4ode,Props);
           
            TH = Omega(:,1);
            OM = Omega(:,2);
            
            %%% stopping 
            OM(OM <= 0) = 0;
            TMP = find((OM <= 0),1);
            TH(TMP:end) = TH(TMP-1);
            
            results.T = T;
            results.THETA = TH;
            results.OMEGA = OM;
        end
    end
    
    methods(Static)
        function domegdt = Motor_EOM(t,omeg,Props)
        c = Props.c;
        b = Props.b;
        Tf = Props.Tf;
        
        
        Tres = Tf + b*omeg(2) + c*omeg(2).^2;
        Tmot = Props.Tstall -Props.Tstall/Props.Omegnoload*omeg(2);
        
        Tmot(Tmot >= Props.Tstall) = Props.Tstall;
        
        domegdt =[omeg(2); (Tmot- Tres)/Props.Inert];
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TorquefromCoulombFrictionEditField.Enable = 'on';           
            Pltdata = Motor_Dynamics(app);
            time = Pltdata.T;
            TH = Pltdata.THETA;
            om = Pltdata.OMEGA;
            
           %%%% Motor rotor
           Rotlength = 0.9;
           Rotor_PPos = [Rotlength*cos(TH),Rotlength*sin(TH)];
           Rotor_MPos = [-Rotlength*cos(TH),-Rotlength*sin(TH)];
           circx = 0.5*cos(0:.001:6.29);
           circy = 0.5*sin(0:.001:6.29);
           
            %%%% changing the number of frames
            FR = 50;
            NUM_PLT_FRAMES = floor(length(om)/FR);


            ylim(app.UIAxes,[0,70])
            xlim(app.UIAxes,[time(1),time(end)])
            ylim(app.UIAxes2,[-1,1])
            xlim(app.UIAxes2,[-1,1])

            
            for INC = 1:NUM_PLT_FRAMES
                PTPOINT = FR*INC;
                
                plot(app.UIAxes,time(1:PTPOINT),om(1:PTPOINT))
                trnd = round(time(PTPOINT),3);
                ornd = round(om(PTPOINT),4);
                text(app.UIAxes,2.5,67,strcat('t = ',num2str(trnd),' s'),'FontSize',14)
                text(app.UIAxes,4.25,67,strcat('\omega = ',num2str(ornd),' rad/s'),'FontSize',14)
                
                cla(app.UIAxes2)
                plot(app.UIAxes2,circx,circy,'Color','k')
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,[Rotor_PPos(PTPOINT,1),Rotor_MPos(PTPOINT,1)],[Rotor_PPos(PTPOINT,2),Rotor_MPos(PTPOINT,2)],'Color','c','LineWidth',3)
                plot(app.UIAxes2,0,0,'o','Color','k','LineWidth',3)
                drawnow
            end

        end

        % Value changed function: TorquefromCoulombFrictionEditField
        function TorquefromCoulombFrictionEditFieldValueChanged(app, event)
           
            %%% dead function I can't delete
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {502, 502};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            app.TorquefromCoulombFrictionEditFieldLabel = uilabel(app.LeftPanel);
            app.TorquefromCoulombFrictionEditFieldLabel.Enable = 'off';
            app.TorquefromCoulombFrictionEditFieldLabel.Text = 'Torque from Coulomb Friction';
            app.TorquefromCoulombFrictionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TorquefromCoulombFrictionEditField.ValueChangedFcn = createCallbackFcn(app, @TorquefromCoulombFrictionEditFieldValueChanged, true);
            app.TorquefromCoulombFrictionEditField.Enable = 'off';
            app.ViscousDampingConstantEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ViscousDampingConstantEditFieldLabel = uilabel(app.LeftPanel);
            app.ViscousDampingConstantEditFieldLabel.Text = 'Viscous Damping Constant';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'Assignment 1', 'Assignment 2'};
            app.Switch.Value = 'Assignment 1';
            app.MotorButtonGroup = uibuttongroup(app.LeftPanel);
            app.MotorButtonGroup.Title = 'Motor';
            app.AButton = uitogglebutton(app.MotorButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.MotorButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.MotorButtonGroup);
            app.CButton.Text = 'C';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Angular Speed of Motor')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, '\omega (m/s)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motor_spindown

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end