classdef SDnV_lab3_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea2               matlab.ui.control.TextArea
        TextArea2Label          matlab.ui.control.Label
        Lamp_4                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        ConnecttoArduinoButton  matlab.ui.control.Button
        StopReadingButton       matlab.ui.control.Button
        StrartReadingButton     matlab.ui.control.Button
        GoalsHowToLabel         matlab.ui.control.Label
        SystemDynamicsandVibrationsLab3TimeSpecificationLabel  matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        ExportDataButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StrartReadingButton
        function StrartReadingButtonPushed(app, event)
            
            
            % tell arduino to start recording
            
            writeline(app.sp,"*IDN?")


            % get location of file 


        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
          if (isempty(app.sp)==1)
            app.Lamp.Color = [1 0 0];
            spl = serialportlist;
            for i = 1:length(spl)
            serialObjectTemp = serialport(spl(i), 9600);
            configureTerminator(serialObjectTemp,"LF");
            writeline(serialObjectTemp,"serialConnectNow")
            pause(0.5)
              % if serial available and reads "amHere"
              TtempText = readline(serialObjectTemp);
              app.TextArea2.Value = TtempText;
              if (class(TtempText) == "string")
                TtempText = splitlines(TtempText);
                if (TtempText(1) == "amHere")
                    app.sp = serialObjectTemp;
                    %clear serialObjectTemp;                  
                    app.Lamp.Color = [0 1 0];
                    app.Lamp_3.Color = [0 1 0];
                end
              end
            end
          end      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SystemDynamicsandVibrationsLab3TimeSpecificationLabel = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrationsLab3TimeSpecificationLabel.Text = {'System Dynamics and Vibrations'; 'Lab 3: Time Specification'};
            app.GoalsHowToLabel = uilabel(app.UIFigure);
            app.GoalsHowToLabel.Text = {'Goals:'; ''; ''; 'How To:'};
            app.StrartReadingButton = uibutton(app.UIFigure, 'push');
            app.StrartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StrartReadingButtonPushed, true);
            app.StrartReadingButton.Text = 'Strart Reading';
            app.StopReadingButton = uibutton(app.UIFigure, 'push');
            app.StopReadingButton.Text = 'Stop Reading';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_4.Visible = 'off';
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab3_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end