classdef SDnV_lab_GUI_InvPend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MotorControllerAngleErrorAngularVelocityErrorLabel_3  matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        Gauge_2                        matlab.ui.control.SemicircularGauge
        Kx_input                       matlab.ui.control.TextArea
        Kth_input                      matlab.ui.control.TextArea
        Kxdot_input                    matlab.ui.control.TextArea
        Kthdot_input                   matlab.ui.control.TextArea
        kdLabel                        matlab.ui.control.Label
        MotorControllerAngleErrorAngularVelocityErrorLabel_2  matlab.ui.control.Label
        SetHangingAngleandCenterPositionButton  matlab.ui.control.Button
        ConnecttoArduinoButton         matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        StartFeedbackControllerButton  matlab.ui.control.Button
        SystemDynamicsandVibrations_______________Label  matlab.ui.control.Label
        Gauge                          matlab.ui.control.SemicircularGauge
        TextArea2                      matlab.ui.control.TextArea
    end

    % Written be William Monroe Hochstedler
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
        iCOM
        TrialPeriod
        HangingAngleEnc
        goalAngle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          while isempty(app.sp) == true
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [0.68,0.38,0.05];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [0.68,0.38,0.05];
                        app.kdLabel.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.Kx_input.Visible = 'on';
                        app.Kth_input.Visible = 'on';
                        app.Kxdot_input.Visible = 'on';
                        app.Kthdot_input.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [0.68,0.38,0.05];
                        app.SetHangingAngleandCenterPositionButton.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.ConnecttoArduinoButton.BackgroundColor = [0.68,0.38,0.05];
                        app.Gauge_2.Visible = 'on';
                        app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = 'Let the pendulum arm hang loosely at steady state before clicking the [Set] button';
                    end
                end
                if app.Lamp.Color ~= [1.00,0.84,0.65] 
                    app.ConnecttoArduinoButton.Text = ':(';
                end
            end  
          end
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _\_             '; '                                                                 ____:____|__|____:'};
            app.TextArea2.Position = [828 408 49 38];
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _|_             '; '                                                                ____:____|__|____:'};
            app.TextArea2.Position = [826 408 49 38];
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _/_             '; '                                                               ____:____|__|____:'};
            app.TextArea2.Position = [824 408 49 38];
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _|_             '; '                                                                ____:____|__|____:'};
            app.TextArea2.Position = [826 408 49 38];
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _\_             '; '                                                                 ____:____|__|____:'};
            app.TextArea2.Position = [828 408 49 38];
            pause(0.5);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _|_             '; '                                                                ____:____|__|____:'};
            app.TextArea2.Position = [826 408 49 38];


            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [0.68,0.38,0.05];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [0.68,0.38,0.05];
                        app.kdLabel.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.Kx_input.Visible = 'on';
                        app.Kth_input.Visible = 'on';
                        app.Kxdot_input.Visible = 'on';
                        app.Kthdot_input.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [0.68,0.38,0.05];
                        app.SetHangingAngleandCenterPositionButton.Visible = 'on';
                        pause(0.5);
                        app.Lamp.Color = [1.00,0.84,0.65];
                        app.Gauge_2.Visible = 'on';
                        app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = 'Let the pendulum arm hang loosely at steady state before clicking the [Set] button';
                    end
                end
                if app.Lamp.Color ~= [1.00,0.84,0.65] 
                    app.ConnecttoArduinoButton.Text = 'Try Again';
                end
            end  
        end

        % Button pushed function: 
        % SetHangingAngleandCenterPositionButton
        function SetHangingAngleandCenterPositionButtonPushed(app, event)
            writeline(app.sp,"HangAng")
            TtempText = readline(app.sp);
            TtempText = splitlines(TtempText);            
            app.TextArea2.Value = TtempText(1); 
            if (TtempText(1) == "amHereHA")
                app.StartFeedbackControllerButton.Visible = 'on';          
                app.HangingAngleEnc = str2double(readline(app.sp));
            else
                app.TextArea2.Value = "error";
            end   
            app.TrialPeriod = 20;
            app.Gauge_2.Visible = 'off';
            app.Gauge.Visible = 'on';
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = {'1) Now move the pendulum about 170 to 190 degrees (upright)';'2) loosely support the pendulum';'3) prepare to let go';'4) click [Start] and let go'};

        end

        % Button pushed function: StartFeedbackControllerButton
        function StartFeedbackControllerButtonPushed(app, event)

            % disable inputs until after recording stopped
              app.StartFeedbackControllerButton.Visible = 'off';
              app.SetHangingAngleandCenterPositionButton.Visible = 'off';
              app.StopButton.Visible = 'on';
              app.Kx_input.Editable = 'off';
              app.Kth_input.Editable = 'off';
              app.Kxdot_input.Editable = 'off';
              app.Kthdot_input.Editable = 'off';  
              app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = {'Press the [Stop] button,';'press either bummper,';'or';'wait 15 seconds';'to stop the run'};

              
            % tell the arduino the kp and kd,
            % but first check if they are any good
              kx = str2double(app.Kx_input.Value);
              kth = str2double(app.Kth_input.Value);
              kxdot = str2double(app.Kxdot_input.Value);
              kthdot = str2double(app.Kxdot_input.Value);
              
            % no input error
              if num2str(kx) == "NaN"  || num2str(kth) == "NaN" || num2str(kxdot) == "NaN" || num2str(kthdot) == "NaN"
                  app.TextArea2.Value = {'-missing-','Gain-Error'};
                  app.StartFeedbackControllerButton.Visible = 'on';
                  app.Kx_input.Editable = 'on';
                  app.Kth_input.Editable = 'on';
                  app.Kxdot_input.Editable = 'on';
                  app.Kthdot_input.Editable = 'on';
                  return
              end
              
            % tell arduino to start recording
              writeline(app.sp,"start")
              %pause(0.1)
              writeline(app.sp,num2str(kx))
              writeline(app.sp,num2str(kth))
              writeline(app.sp,num2str(kxdot))
              writeline(app.sp,num2str(kthdot))
              %pause(1)
              %flush(app.sp);
               TtempText = readline(app.sp); 
               TtempText = splitlines(TtempText);
%               app.TextArea2.Value = TtempText;

            % initialize/clear OverTimer
              OverTimer = tic;
              
            % loop that collects data from serial port
              while (toc(OverTimer) < app.TrialPeriod) 
                  app.TextArea2.Value = TtempText;
                  pause(0.2)
                  app.TextArea2.Value = ' ';
                  pause(0.2)
              end

            % if time lapses, run stop read function
              StopButtonPushed(app, event) 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % stop arduino
              writeline(app.sp,"stop")
            
            % enable inputs until after recording stopped
              app.StopButton.Visible = 'off';
              app.SetHangingAngleandCenterPositionButton.Visible = 'on';
              app.Kx_input.Editable = 'on';
              app.Kth_input.Editable = 'on';
              app.Kxdot_input.Editable = 'on';
              app.Kthdot_input.Editable = 'on';
              app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = 'Recenter the the pendulum cart by clicking the [Set] button again';


%             % tell arduino to stop recording
%               writeline(app.sp,"stop")
%               TtempText = readline(app.sp); 
%               TtempText = splitlines(TtempText);
%               app.TextArea2.Value = TtempText;
%               
%             % initialize/clear datasets and OverTimer
%               temptime = 0;
%               app.Acceleration = 0;
%               app.Time = 0;
%               
%             % receive data
%             for ii = 1:250
%                 dataStr = readline(app.sp);  
%               % convert incoming string into double
%                 dataNum = str2double(dataStr);   
%               % now record time as the data sets are sent seporately
%                 temptime = temptime + 1/10;
%                 app.Time = [app.Time; temptime];
%                 app.Acceleration = [app.Acceleration; dataNum];
%             end
%               
%               
%             if isempty(app.Acceleration) == 1
%                 app.TextArea2.Value = {'No Data','Collected'};
%                 return
%             end
%             % plot the data
%               app.Acceleration = ...
%                   app.Acceleration(1:find(app.Acceleration,1,'last'));
%               app.Acceleration = (app.Acceleration -...
%                   app.HangingAngleEnc)*360/400;
%               
%               endingLine = (app.HangingAngleEnc+app.goalAngle)*ones(length(app.Acceleration)-1,1);
%               plot(app.UIAxesEncoder,...
%                   app.Time(2:length(app.Acceleration)), ...
%                   endingLine,'k-')
%               hold(app.UIAxesEncoder, 'on' )
%               plot(app.UIAxesEncoder, ...
%                   app.Time(2:length(app.Acceleration)), ...
%                   app.Acceleration(2:end)*360/400,'b-')
%               hold(app.UIAxesEncoder, 'off' )
%               %flush(app.sp);
%             % enable start button
%               app.StartFeedbackControllerButton.Enable = 'on';
%               app.Kx_input.Editable = 'on';
%               app.Kth_input.Editable = 'on';
%               app.Kxdot_input.Editable = 'on';
%               app.ButtonGroup.Enable = 'on';
%               app.FilenameTextArea.Enable = 'on';
%               app.ExportDataButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Editable = 'off';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-90 90];
            app.Gauge.MajorTicks = [];
            app.Gauge.MinorTicks = [];
            app.Gauge.Visible = 'off';
            app.Gauge.Value = 10;
            app.SystemDynamicsandVibrations_______________Label = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrations_______________Label.Text = {'System Dynamics and Vibrations'; '                                    Inverted Pendulum                 _|_             '; '                                                                ____:____|__|____:'};
            app.StartFeedbackControllerButton = uibutton(app.UIFigure, 'push');
            app.StartFeedbackControllerButton.ButtonPushedFcn = createCallbackFcn(app, @StartFeedbackControllerButtonPushed, true);
            app.StartFeedbackControllerButton.Visible = 'off';
            app.StartFeedbackControllerButton.Text = {'Start '; 'Feedback'; 'Controller'};
            app.Lamp = uilamp(app.UIFigure);
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.SetHangingAngleandCenterPositionButton = uibutton(app.UIFigure, 'push');
            app.SetHangingAngleandCenterPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetHangingAngleandCenterPositionButtonPushed, true);
            app.SetHangingAngleandCenterPositionButton.Visible = 'off';
            app.SetHangingAngleandCenterPositionButton.Text = {'Set'; 'Hanging Angle'; 'and'; 'Center Position'; ''};
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2 = uilabel(app.UIFigure);
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Visible = 'off';
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Text = '';
            app.kdLabel = uilabel(app.UIFigure);
            app.kdLabel.Visible = 'off';
            app.kdLabel.Text = {'k1 =         '; 'k2 =         '; 'k3 =         '; 'k4 =         '};
            app.Kthdot_input = uitextarea(app.UIFigure);
            app.Kthdot_input.Visible = 'off';
            app.Kthdot_input.Value = {'0'};
            app.Kxdot_input = uitextarea(app.UIFigure);
            app.Kxdot_input.Visible = 'off';
            app.Kxdot_input.Value = {'0'};
            app.Kth_input = uitextarea(app.UIFigure);
            app.Kth_input.Visible = 'off';
            app.Kth_input.Value = {'0'};
            app.Kx_input = uitextarea(app.UIFigure);
            app.Kx_input.Visible = 'off';
            app.Kx_input.Value = {'0'};
            app.Gauge_2 = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_2.Limits = [-90 90];
            app.Gauge_2.MajorTicks = [];
            app.Gauge_2.MinorTicks = [];
            app.Gauge_2.Visible = 'off';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_3 = uilabel(app.UIFigure);
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_3.Text = 'Let the pendulum arm hang loosely at steady state before clicking the [Set] button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_InvPend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end