classdef SDnV_lab_GUI_Lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea2               matlab.ui.control.TextArea
        Lamp_4                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        ConnecttoArduinoButton  matlab.ui.control.Button
        StopMotorButton         matlab.ui.control.Button
        StartReadingButton      matlab.ui.control.Button
        SystemDynamicsandVibrationsLab2MotorSpindownLabel  matlab.ui.control.Label
        FilenameTextArea        matlab.ui.control.TextArea
        FilenameTextAreaLabel   matlab.ui.control.Label
        ExportDataButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Angle % Description
        Velocity % Description
        Time % Description
        sp % serial port object
        STOPPED
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartReadingButton
        function StartReadingButtonPushed(app, event)
               app.Lamp_3.Enable = 'off';
               app.Lamp_2.Enable = 'on';          
            
            % tell arduino to start recording
              writeline(app.sp,"startDataread")
              pause(12)
%               app.Lamp_2.Color.Enable = 'on';
%               app.Lamp_3.Color.Enable = 'off';   
              app.Angle = zeros(1000,1);

            % tell arduino to stop recording
              writeline(app.sp,"STOP")
              
            % initialize/clear datasets and OverTimer
              app.Time = 0.012;
              
            % receive data
            for ii = 1:1000
              % now record the data
                app.Angle(ii) =  str2double(readline(app.sp));
            end

            if isempty(app.Angle) == 1
                app.TextArea2.Value = {'No Data','Collected'};
                return
            end
            
            % plot the data
              app.Angle = ...
                  app.Angle(1:find(app.Angle,1,'last'));
              plot(app.UIAxes, ...
                  linspace(0,length(app.Angle)*app.Time,length(app.Angle)), ...
                  app.Angle(1:end)*360/400,'b-')


              StopMotorButtonPushed(app);
            
            
            % get location of file 


        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
          if (isempty(app.sp)==1)
            app.Lamp.Color = [1 0 0];
            spl = serialportlist;
            for i = 1:length(spl)
              serialObjectTemp = serialport(spl(i), 9600);
              configureTerminator(serialObjectTemp,"LF");
              writeline(serialObjectTemp,"serialConnectNow")
              pause(0.5)
              % if serial available and reads "amHere"
              app.TextArea2.Value = spl(i);
%               if isempty(readline(serialObjectTemp)) == 0
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;
                if (class(TtempText) == "string")
                  TtempText = splitlines(TtempText);
                  if (TtempText(1) == "amHere")
                      clear serialObjectTemp;                     
                      app.sp = serialport(spl(i), 9600);
                      app.Lamp.Color = [0 1 0];
%                       status = getpinstatus(app.sp);
%                       app.TextArea2.Value = status;
                  end
                end
%               end
            end
          end      
        end

        % Button pushed function: StopMotorButton
        function StopMotorButtonPushed(app, event)
               app.Lamp_3.Enable = 'on';
               app.Lamp_2.Enable = 'off';
            %ylim(app.UIAxes,[app.Angle(min),70])
              writeline(app.sp,"STOP")
              app.TextArea2.Value = "STOP";
              % make sure arrays are the same size
              app.Time = linspace(0,length(app.Angle)*app.Time,length(app.Angle))';
             
            % calculate position
  app.Velocity = (-app.Angle(2:end) + app.Angle(1:end-1))./ ...
                 (app.Time(2:end) - app.Time(1:end-1))*2*3.14/400;
      
  plot(app.UIAxes,app.Time(2:end),app.Velocity,'k-')           
            
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
 % export data to text file
% Shape = {'Time';'Angle';'Velocity';'Position'};
%   app.TextArea2.Value = string(length(app.Time(1:length(app.Velocity))));
%       pause(1)
%       app.TextArea2.Value = string(length(app.Velocity));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Angle(1:length(app.Velocity))));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Position));
%       pause(1)
     TimeT = app.Time(1:length(app.Velocity));
     AngVelT = app.Velocity;
 T = table(TimeT,AngVelT);
 % rename
 if (isempty(app.FilenameTextArea.Value))
     app.TextArea2.Value = 'error, no name';
 else
 filemeData = string(app.FilenameTextArea.Value);
 writetable(T,filemeData);
%  type tabledata.txt
 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angular Velocity')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Radians')
            zlabel(app.UIAxes, 'Z')
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Text = 'Filename:';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.SystemDynamicsandVibrationsLab2MotorSpindownLabel = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrationsLab2MotorSpindownLabel.Text = {'System Dynamics and Vibrations'; 'Lab 2: Motor Spindown'};
            app.StartReadingButton = uibutton(app.UIFigure, 'push');
            app.StartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StartReadingButtonPushed, true);
            app.StartReadingButton.Text = 'Start Reading';
            app.StopMotorButton = uibutton(app.UIFigure, 'push');
            app.StopMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StopMotorButtonPushed, true);
            app.StopMotorButton.Text = {'Stop '; 'Motor'};
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_4.Visible = 'off';
            app.TextArea2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_Lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end