classdef SDnV_lab_GUI_Lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea2               matlab.ui.control.TextArea
        Lamp_4                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        ConnecttoArduinoButton  matlab.ui.control.Button
        StopReadingButton       matlab.ui.control.Button
        StrartReadingButton     matlab.ui.control.Button
        SystemDynamicsandVibrationsLab5ExperimentalRootLocusLabel  matlab.ui.control.Label
        FilenameTextArea        matlab.ui.control.TextArea
        FilenameTextAreaLabel   matlab.ui.control.Label
        ExportDataButton        matlab.ui.control.Button
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
        STOPPED
        iCOM
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StrartReadingButton
        function StrartReadingButtonPushed(app, event)
            
            
            % tell arduino to start recording
              writeline(app.sp,"startDataread")
              pause(0.5)
%               app.Lamp_2.Color.Enable = 'on';
%               app.Lamp_3.Color.Enable = 'off';
              OverTimer = tic;
              temptime = 0;
              app.Time = 0;
              app.Acceleration = 0;
              app.STOPPED = 0;
              while app.STOPPED <1 
              while (60 > toc(OverTimer)) 
        
                  % read line and if something is there record data
                 dataStr = readline(app.sp);  
                    if isempty(dataStr) == 0
                     dataNum = str2double(dataStr);   
                     
                     %
                     %dataStr = splitlines(dataStr);
                     %now record time as thes data sets are sent seporate
                     temptime = str2double(readline(app.sp));
                     
                     app.Time = [app.Time; temptime];
                     app.Acceleration = [app.Acceleration; dataNum];
                     app.TextArea2.Value = dataStr;
                    end
              end
                               
%             % change the number of frames
%             FR = 50;
%             NUM_PLT_FRAMES = floor(length(om)/FR);
                 
                % record data and time arrays

              end


            % get location of file 


        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
            app.Lamp.Color = [1 0 0];
            spl = serialportlist;
%             for i = 1:length(spl)
              app.iCOM = app.iCOM + 1;
              serialObjectTemp = serialport(spl(app.iCOM), 9600);
              configureTerminator(serialObjectTemp,"LF");
              writeline(serialObjectTemp,"serialConnectNow")
              pause(0.5)
              % if serial available and reads "amHere"
              app.TextArea2.Value = spl(app.iCOM);
%               if isempty(readline(serialObjectTemp)) == 0
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;
                if (class(TtempText) == "string")
                  TtempText = splitlines(TtempText);
                  if (TtempText(1) == "amHere")
                      clear serialObjectTemp;                     
                      app.sp = serialport(spl(app.iCOM), 9600);
                      app.Lamp.Color = [0 1 0];
%                       status = getpinstatus(app.sp);
%                       app.TextArea2.Value = status;
                  end
                end
%               end
%             end
            end      
        end

        % Button pushed function: StopReadingButton
        function StopReadingButtonPushed(app, event)
%               app.Lamp_3.Color.Enable = "ON";
%               app.Lamp_2.Color.Enable = "OFF";
            %ylim(app.UIAxes,[app.Acceleration(min),70])
              app.STOPPED = 1;
              xlim(app.UIAxes,[app.Time(1),app.Time(end)])
              xlim(app.UIAxes_2,[app.Time(1),app.Time(end)])
              xlim(app.UIAxes_3,[app.Time(1),app.Time(end)])
              offsetA = mean(app.Acceleration(end-21:end-1));
              app.Acceleration = app.Acceleration(1:end-1) - offsetA;
              app.Time = app.Time(1:end-1)/1000;
              plot(app.UIAxes,app.Time,app.Acceleration,'g-')
            % calculate position
  app.Velocity = [];
  app.Position = [];
  for ii = 1:length(app.Time)-1
      Vii = (app.Acceleration(ii+1) - app.Acceleration(ii))/(app.Time(ii+1) - app.Time(ii));
      app.Velocity = [app.Velocity; Vii];
  end  
      for ii = 1:length(app.Time)-length(app.Velocity)
          
         app.Velocity = [app.Velocity; 0]; 
      end


  plot(app.UIAxes_2,app.Time,app.Velocity,'g-')
  for ii = 1:length(app.Time)-2
      Pii = (app.Velocity(ii+1) - app.Velocity(ii))/(app.Time(ii+1) - app.Time(ii));
      app.Position = [app.Position; Pii];
      
  end
      for ii = 1:length(app.Time)-length(app.Position)
          
         app.Position = [app.Position; 0]; 
      end
      plot(app.UIAxes_3,app.Time,app.Position,'g-')      


        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
 % export data to text file
% Shape = {'Time';'Acceleration';'Velocity';'Position'};
%   app.TextArea2.Value = string(length(app.Time(1:length(app.Velocity))));
%       pause(1)
%       app.TextArea2.Value = string(length(app.Velocity));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Acceleration(1:length(app.Velocity))));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Position));
%       pause(1)
     TimeT = app.Time(4:length(app.Velocity));
     AccT = app.Acceleration(4:length(app.Velocity));
     VelT = app.Velocity(4:end);
     PosT = app.Position(4:end);
 T = table(TimeT,AccT);
 % rename
 
 filemeData = string(app.FilenameTextArea.Value);
 writetable(T,filemeData);
%  type tabledata.txt
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Acceleration')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, 'Velocity')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Position')
            zlabel(app.UIAxes_3, 'Z')
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Text = 'Filename:';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.SystemDynamicsandVibrationsLab5ExperimentalRootLocusLabel = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrationsLab5ExperimentalRootLocusLabel.Text = {'System Dynamics and Vibrations'; 'Lab 5: Experimental Root Locus'};
            app.StrartReadingButton = uibutton(app.UIFigure, 'push');
            app.StrartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StrartReadingButtonPushed, true);
            app.StrartReadingButton.Text = 'Strart Reading';
            app.StopReadingButton = uibutton(app.UIFigure, 'push');
            app.StopReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReadingButtonPushed, true);
            app.StopReadingButton.Text = 'Stop Reading';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_4.Visible = 'off';
            app.TextArea2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_Lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end