classdef SDnV_lab_GUI_Tower002 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InvertedPendulumButton     matlab.ui.control.Button
        ModesButton                matlab.ui.control.Button
        FiltersButton              matlab.ui.control.Button
        ControlsButton             matlab.ui.control.Button
        LinearizationMatrixButton  matlab.ui.control.Button
        RootLocusButton            matlab.ui.control.Button
        TimeSpecificationButton    matlab.ui.control.Button
        FreeVibration2ndODEButton  matlab.ui.control.Button
        MotorSpindown1stODEButton  matlab.ui.control.Button
        MotorCharacterizationNoLoadSpeedButton  matlab.ui.control.Button
        MotorCharacterizationStallTorqueButton  matlab.ui.control.Button
        ResonanceOffSetMassButton  matlab.ui.control.Button
        SerialPortAlertsLabel      matlab.ui.control.Label
        MotoronoffButton           matlab.ui.control.Button
        HzSlider                   matlab.ui.control.Slider
        HzSliderLabel              matlab.ui.control.Label
        TextArea2                  matlab.ui.control.TextArea
        Lamp                       matlab.ui.control.Lamp
        ConnecttoArduinoButton     matlab.ui.control.Button
        StopReadingButton          matlab.ui.control.Button
        StrartReadingButton        matlab.ui.control.Button
        SystemDynamicsandVibrationsLabel  matlab.ui.control.Label
        FilenameTextArea           matlab.ui.control.TextArea
        FilenameTextAreaLabel      matlab.ui.control.Label
        ExportDataButton           matlab.ui.control.Button
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
        STOPPED
        iCOM
        StallTorqueLABtimer
        HzPlotter
        MOOCount
        OverTimer
        readEncoder
    end
    
    methods (Access = private)
        
        function clearSpace(app)
            app.UIFigure.Color = [0.72 0.84 0.92];
            app.MotorSpindown1stODEButton.Enable = 'off';
            app.MotorSpindown1stODEButton.Visible = 'off';
            app.FreeVibration2ndODEButton.Enable = 'off';
            app.FreeVibration2ndODEButton.Visible = 'off';
            app.TimeSpecificationButton.Enable = 'off';
            app.TimeSpecificationButton.Visible = 'off';
            app.RootLocusButton.Enable = 'off';
            app.RootLocusButton.Visible = 'off';  
            app.ResonanceOffSetMassButton.Enable = 'off';
            app.ResonanceOffSetMassButton.Visible = 'off';         
            app.MotorCharacterizationNoLoadSpeedButton.Enable = 'off';
            app.MotorCharacterizationNoLoadSpeedButton.Visible = 'off';
            app.MotorCharacterizationStallTorqueButton.Enable = 'off';
            app.MotorCharacterizationStallTorqueButton.Visible = 'off';
            app.FiltersButton.Enable = 'off';
            app.FiltersButton.Visible = 'off';
            app.ControlsButton.Enable = 'off';
            app.ControlsButton.Visible = 'off';
            app.LinearizationMatrixButton.Enable = 'off';
            app.LinearizationMatrixButton.Visible = 'off';
            app.ModesButton.Enable = 'off';
            app.ModesButton.Visible = 'off';
            app.InvertedPendulumButton.Enable = 'off';
            app.InvertedPendulumButton.Visible = 'off';           
%             app.ConnecttoArduinoButton.Enable = 'off';
%             app.ConnecttoArduinoButton.Visible = 'off';
            app.Lamp.Enable = 'off';
            app.Lamp.Visible = 'off';
            app.ConnecttoArduinoButton.FontSize = 14;
            app.ConnecttoArduinoButton.Position = [132 19 134 29];
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.iCOM = 0;
        end
        
        function TurnOnPlot(app)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Hz')
            app.UIAxes.Color = [0.902 0.902 0.902];
            app.UIAxes.FontSize = 14;
            app.UIAxes.Visible = 'on';
            app.UIAxes.Position = [370 13 400 206];
        end
        
        
%         function figure1_WindowKeyPressFcn(hObject, eventdata, handles)
%             switch eventdata.Key
%               case 'a'
%         movefront_Callback(handles.movefront, [], handles); 
%                case 'b'
%         moveback_Callback(handles.moveback, [], handles); 
%             end
%         end
%         
%        g = figure('KeyPressFcn', @keyPress)
%        MyButton = uicontrol('Style', 'pushbutton','Callback',@task);
%       function task(src, e)
%          disp('button press');
%       end
%       function keyPress(src, e)
%          switch e.Key
%              case 'c'
%                  task(MyButton, []);
%          end
%       end       


function recordEncoderTicks(app)
              app.Time = 0;
              app.Acceleration = 0;
              app.OverTimer = tic;
              app.MotoronoffButton.Enable = 'off';
             while (10 > toc(app.OverTimer)) 
        
                  % read line and if something is there record data
                 dataStr = readline(app.sp);  
                    if isempty(dataStr) == 0
                     dataNum = str2double(dataStr);                       
                     temptime = str2double(readline(app.sp));
                     
                     app.Time = [app.Time; temptime/1000];
                     app.Acceleration = [app.Acceleration; dataNum];
                     app.TextArea2.Value = dataStr;
                    end
             end
              xlim(app.UIAxes,[app.Time(1),app.Time(end)]);
              clf(app.UIAxes);
              plot(app.UIAxes,app.Time,app.Acceleration,'k-') ;
              app.MotoronoffButton.Enable = 'on';
              
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
            app.Lamp.Color = [1 0 0];
            spl = serialportlist;
%             for i = 1:length(spl)
              app.iCOM = app.iCOM + 1;
              if (app.iCOM > length(spl))
                  app.iCOM = 1;
              end
              serialObjectTemp = serialport(spl(app.iCOM), 9600);
              configureTerminator(serialObjectTemp,"LF");
              writeline(serialObjectTemp,"serialConnectNow")
              pause(0.5)
              % if serial available and reads "amHere"
              app.TextArea2.Value = spl(app.iCOM);
%               if isempty(readline(serialObjectTemp)) == 0
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;
                if (class(TtempText) == "string")
                  TtempText = splitlines(TtempText);
                  if (TtempText(1) == "amHere")
                      clear serialObjectTemp;                     
                      app.sp = serialport(spl(app.iCOM), 9600);
                      app.ConnecttoArduinoButton.Text = 'Connected';
                      app.Lamp.Color = [0 1 0];
%                       status = getpinstatus(app.sp);
%                       app.TextArea2.Value = status;
            app.MotorSpindown1stODEButton.Enable = 'on';
            app.MotorSpindown1stODEButton.Visible = 'on';
            app.FreeVibration2ndODEButton.Enable = 'on';
            app.FreeVibration2ndODEButton.Visible = 'on';
            app.TimeSpecificationButton.Enable = 'on';
            app.TimeSpecificationButton.Visible = 'on';            
            app.RootLocusButton.Enable = 'on';
            app.RootLocusButton.Visible = 'on';              
            app.ResonanceOffSetMassButton.Enable = 'on';
            app.ResonanceOffSetMassButton.Visible = 'on';
            app.MotorCharacterizationNoLoadSpeedButton.Enable = 'on';
            app.MotorCharacterizationNoLoadSpeedButton.Visible = 'on';
            app.MotorCharacterizationStallTorqueButton.Enable = 'on';
            app.MotorCharacterizationStallTorqueButton.Visible = 'on';
            app.FiltersButton.Enable = 'on';
            app.FiltersButton.Visible = 'on';
            app.ControlsButton.Enable = 'on';
            app.ControlsButton.Visible = 'on';
            app.LinearizationMatrixButton.Enable = 'on';
            app.LinearizationMatrixButton.Visible = 'on';
            app.ModesButton.Enable = 'on';
            app.ModesButton.Visible = 'on';
            app.InvertedPendulumButton.Enable = 'on';
            app.InvertedPendulumButton.Visible = 'on';
                  end
                end
%               end
%             end
          if app.Lamp.Color ~= [0 1 0]                           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%???????????????//////////////////////////////////////////
            app.ConnecttoArduinoButton.Text = 'Try Again';
          end
            end      
        end

        % Button pushed function: StrartReadingButton
        function StrartReadingButtonPushed(app, event)
            
            
            % tell arduino to start recording
%               writeline(app.sp,"startDataread")
              pause(0.5)
%               app.Lamp_2.Color.Enable = 'on';
%               app.Lamp_3.Color.Enable = 'off';           
              app.OverTimer = tic;
              temptime = 0;
              app.Time = 0;
              app.Acceleration = 0;
              app.STOPPED = 0;
              while app.STOPPED <1 
              while (60 > toc(app.OverTimer)) 
        
                  % read line and if something is there record data
                 dataStr = readline(app.sp);  
                    if isempty(dataStr) == 0
                     dataNum = str2double(dataStr);   
                     
                     %
                     %dataStr = splitlines(dataStr);
                     %now record time as thes data sets are sent seporate
                     temptime = str2double(readline(app.sp));
                     
                     app.Time = [app.Time; temptime];
                     app.Acceleration = [app.Acceleration; dataNum];
                     app.TextArea2.Value = dataStr;
                    end
              end
                               
%             % change the number of frames
%             FR = 50;
%             NUM_PLT_FRAMES = floor(length(om)/FR);
                 
                % record data and time arrays

              end


            % get location of file 


        end

        % Button pushed function: StopReadingButton
        function StopReadingButtonPushed(app, event)
%               app.Lamp_3.Color.Enable = "ON";
%               app.Lamp_2.Color.Enable = "OFF";
            %ylim(app.UIAxes,[app.Acceleration(min),70])
              app.STOPPED = 1;
              xlim(app.UIAxes,[app.Time(1),app.Time(end)])
              xlim(app.UIAxes_2,[app.Time(1),app.Time(end)])
              xlim(app.UIAxes_3,[app.Time(1),app.Time(end)])
              offsetA = mean(app.Acceleration(end-21:end-1));
              app.Acceleration = app.Acceleration(1:end-1) - offsetA;
              app.Time = app.Time(1:end-1);
              clf(app.UIAxes)
              plot(app.UIAxes,app.Time,app.Acceleration,'k-')
%             % calculate position
%   app.Velocity = [];
%   app.Position = [];
%   for ii = 1:length(app.Time)-1
%       Vii = (app.Acceleration(ii+1) - app.Acceleration(ii))/(app.Time(ii+1) - app.Time(ii));
%       app.Velocity = [app.Velocity; Vii];
%   end  
%       for ii = 1:length(app.Time)-length(app.Velocity)
%           
%          app.Velocity = [app.Velocity; 0]; 
%       end
%  
%       
%        
% 
%   plot(app.UIAxes_2,app.Time,app.Velocity,'g-')
%   for ii = 1:length(app.Time)-2
%       Pii = (app.Velocity(ii+1) - app.Velocity(ii))/(app.Time(ii+1) - app.Time(ii));
%       app.Position = [app.Position; Pii];
%       
%   end
%       for ii = 1:length(app.Time)-length(app.Position)
%           
%          app.Position = [app.Position; 0]; 
%       end
%       plot(app.UIAxes_3,app.Time,app.Position,'g-')      


        end

        % Button pushed function: MotoronoffButton
        function MotoronoffButtonPushed(app, event)
            %% run arduino
             writeline(app.sp,num2str(app.HzSlider.Value)) 
             pause(0.15)
             dataStr = readline(app.sp);
             app.TextArea2.Value = dataStr;
            %% ran arduino
            
            
            if (isempty(app.readEncoder) == 0)
              app.MotoronoffButton.Enable = 'off';
              recordEncoderTicks(app)
            end


% % %          app.MOOCount = app.MOOCount + 1;
         %if (isempty(app.HzPlotter) == 1)
                
% % %               if (rem(app.MOOCount,2) == 1)
                 %  writeline(app.sp,"MOO")

                   
%               OverTimer = tic;
%               temptime = 0;
%               app.Time = 0;
%               app.Acceleration = 0;
%               app.STOPPED = 0;
%                while app.STOPPED <1 
%                if (isempty(app.StallTorqueLABtimer) == 0)
%                while (app.StallTorqueLABtimer > toc(OverTimer)) 
%                   % read line and if something is there record data
%                  dataStr = readline(app.sp);  
%                     if isempty(dataStr) == 0
%                      dataNum = str2double(dataStr);
%                      temptime = toc(OverTimer);
%                      app.Time = [app.Time; temptime];
%                      app.Acceleration = [app.Acceleration; dataNum];
%                     end
%                end
%                end
% % %               end
% % %                if (rem(app.MOOCount,2) == 0) 
% % %                  %  writeline(app.sp,"MOO")
% % %                    writeline(app.sp,"0") 
% % %                    pause(0.15)
% % %                    dataStr = readline(app.sp);
% % %                    app.TextArea2.Value = dataStr;
                   
%                    xlim(app.UIAxes,[app.Time(1),app.Time(end)])
%                    clf(app.UIAxes)
%                    plot(app.UIAxes,app.Time,app.Acceleration,'k-')
%                    offsetA = mean(app.Acceleration(end-21:end-1));
%                    app.TextArea2.Value = double2str(offsetA);
% % %                end
      %   end
            % app.TextArea2.Value =  double2str(app.MOOCount);
%          end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
 % export data to text file
% Shape = {'Time';'Acceleration';'Velocity';'Position'};
%   app.TextArea2.Value = string(length(app.Time(1:length(app.Velocity))));
%       pause(1)
%       app.TextArea2.Value = string(length(app.Velocity));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Acceleration(1:length(app.Velocity))));
%       pause(1)
%             app.TextArea2.Value = string(length(app.Position));
%       pause(1)
     TimeT = app.Time(4:length(app.Velocity));
     AccT = app.Acceleration(4:length(app.Velocity));
    % VelT = app.Velocity(4:end);
    % PosT = app.Position(4:end);
 T = table(TimeT,AccT);
 % rename
 
 filemeData = string(app.FilenameTextArea.Value);
 writetable(T,filemeData);
%  type tabledata.txt
        end

        % Button pushed function: 
        % MotorCharacterizationNoLoadSpeedButton
        function MotorCharacterizationNoLoadSpeedButtonPushed(app, event)
            writeline(app.sp,"NoLoadSpeed")
            clearSpace(app)
            app.MotoronoffButton.Enable = 'on';
            app.MotoronoffButton.Visible = 'on';
            app.HzSlider.Enable = 'on';
            app.HzSlider.Visible = 'on';
            app.HzSliderLabel.Text = 'Volts';
            app.HzSlider.Limits = [0 800];
            app.HzSlider.MajorTicks = [0 455 568 682 800];
            app.HzSlider.MajorTickLabels = {'0' '4V' '5V' '6V' '7V'};
            app.StallTorqueLABtimer = 10;
            TurnOnPlot(app)
            app.MOOCount = 0;
            dataStr = readline(app.sp);
            app.TextArea2.Value = dataStr;
            app.UIAxes.Visible = 'on';
            app.readEncoder = 1;
        end

        % Button pushed function: 
        % MotorCharacterizationStallTorqueButton
        function MotorCharacterizationStallTorqueButtonPushed(app, event)
            writeline(app.sp,"StallTorque")
            clearSpace(app)
            app.MotoronoffButton.Enable = 'on';
            app.MotoronoffButton.Visible = 'on';
            app.HzSlider.Enable = 'on';
            app.HzSlider.Visible = 'on';
            app.HzSliderLabel.Text = 'PWM';
            app.HzSlider.Limits = [0 800];
            app.HzSlider.MajorTicks = [0 455 568 682 800];
            app.HzSlider.MajorTickLabels = {'0' '4V' '5V' '6V' '7V'};
            app.StallTorqueLABtimer = 3;
            TurnOnPlot(app)
            app.MOOCount = 0;
            dataStr = readline(app.sp);
            app.TextArea2.Value = dataStr;
            
        end

        % Button pushed function: ResonanceOffSetMassButton
        function ResonanceOffSetMassButtonPushed(app, event)
            writeline(app.sp,"OffSetMass")
            clearSpace(app)
            app.SystemDynamicsandVibrationsLabel.Text = ...
                {'System Dynamics and Vibrations';...
                'Off Set Mass Lab'};
            app.MotoronoffButton.Enable = 'on';
            app.MotoronoffButton.Visible = 'on';
            app.HzSlider.Enable = 'on';
            app.HzSlider.Visible = 'on';
            app.MOOCount = 0;
            dataStr = readline(app.sp);
            app.TextArea2.Value = dataStr;

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: MotorSpindown1stODEButton
        function MotorSpindown1stODEButtonPushed(app, event)
            writeline(app.sp,"MotorSpinDown")
            clearSpace(app)
            app.MotoronoffButton.Enable = 'on';
            app.MotoronoffButton.Visible = 'on';
            app.HzSlider.Enable = 'on';
            app.HzSlider.Value = 700;
%             app.HzSlider.Visible = 'on';
%             app.HzSliderLabel.Text = 'PWM';
%             app.HzSlider.Limits = [0 800];
%             app.HzSlider.MajorTicks = [0 455 568 682 800];
%             app.HzSlider.MajorTickLabels = {'0' '4V' '5V' '6V' '7V'};
            app.StallTorqueLABtimer = 5;
            TurnOnPlot(app)
            app.MOOCount = 0;
            dataStr = readline(app.sp);
            app.TextArea2.Value = dataStr;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Acceleration')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, 'Velocity')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Position')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Visible = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Enable = 'off';
            app.FilenameTextAreaLabel.Visible = 'off';
            app.FilenameTextAreaLabel.Text = 'Filename:';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Enable = 'off';
            app.FilenameTextArea.Visible = 'off';
            app.SystemDynamicsandVibrationsLabel = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrationsLabel.Text = 'System Dynamics and Vibrations';
            app.StrartReadingButton = uibutton(app.UIFigure, 'push');
            app.StrartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StrartReadingButtonPushed, true);
            app.StrartReadingButton.Enable = 'off';
            app.StrartReadingButton.Visible = 'off';
            app.StrartReadingButton.Text = 'Strart Reading';
            app.StopReadingButton = uibutton(app.UIFigure, 'push');
            app.StopReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReadingButtonPushed, true);
            app.StopReadingButton.Enable = 'off';
            app.StopReadingButton.Visible = 'off';
            app.StopReadingButton.Text = 'Stop Reading';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Enable = 'off';
            app.HzSliderLabel.Visible = 'off';
            app.HzSliderLabel.Text = 'Hz';
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [0 4];
            app.HzSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.HzSlider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3', '3.5 ', '4'};
            app.HzSlider.Enable = 'off';
            app.HzSlider.Visible = 'off';
            app.MotoronoffButton = uibutton(app.UIFigure, 'push');
            app.MotoronoffButton.ButtonPushedFcn = createCallbackFcn(app, @MotoronoffButtonPushed, true);
            app.MotoronoffButton.Enable = 'off';
            app.MotoronoffButton.Visible = 'off';
            app.MotoronoffButton.Text = {'Motor'; 'on/off'};
            app.SerialPortAlertsLabel = uilabel(app.UIFigure);
            app.SerialPortAlertsLabel.Text = ' Serial Port Alerts';
            app.ResonanceOffSetMassButton = uibutton(app.UIFigure, 'push');
            app.ResonanceOffSetMassButton.ButtonPushedFcn = createCallbackFcn(app, @ResonanceOffSetMassButtonPushed, true);
            app.ResonanceOffSetMassButton.Enable = 'off';
            app.ResonanceOffSetMassButton.Text = {'Resonance'; 'Off Set Mass'};
            app.MotorCharacterizationStallTorqueButton = uibutton(app.UIFigure, 'push');
            app.MotorCharacterizationStallTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @MotorCharacterizationStallTorqueButtonPushed, true);
            app.MotorCharacterizationStallTorqueButton.Enable = 'off';
            app.MotorCharacterizationStallTorqueButton.Text = {'Motor'; 'Characterization'; 'Stall Torque'};
            app.MotorCharacterizationNoLoadSpeedButton = uibutton(app.UIFigure, 'push');
            app.MotorCharacterizationNoLoadSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @MotorCharacterizationNoLoadSpeedButtonPushed, true);
            app.MotorCharacterizationNoLoadSpeedButton.Enable = 'off';
            app.MotorCharacterizationNoLoadSpeedButton.Text = {'Motor '; 'Characterization'; 'No Load Speed'};
            app.MotorSpindown1stODEButton = uibutton(app.UIFigure, 'push');
            app.MotorSpindown1stODEButton.ButtonPushedFcn = createCallbackFcn(app, @MotorSpindown1stODEButtonPushed, true);
            app.MotorSpindown1stODEButton.Enable = 'off';
            app.MotorSpindown1stODEButton.Text = {'Motor Spindown'; '1st ODE'};
            app.FreeVibration2ndODEButton = uibutton(app.UIFigure, 'push');
            app.FreeVibration2ndODEButton.Enable = 'off';
            app.FreeVibration2ndODEButton.Text = {'Free Vibration'; '2nd ODE'};
            app.TimeSpecificationButton = uibutton(app.UIFigure, 'push');
            app.TimeSpecificationButton.Enable = 'off';
            app.TimeSpecificationButton.Text = {'Time '; 'Specification'};
            app.RootLocusButton = uibutton(app.UIFigure, 'push');
            app.RootLocusButton.Enable = 'off';
            app.RootLocusButton.Text = {'Root'; 'Locus'};
            app.LinearizationMatrixButton = uibutton(app.UIFigure, 'push');
            app.LinearizationMatrixButton.Enable = 'off';
            app.LinearizationMatrixButton.Text = {'Linearization/'; 'Matrix'};
            app.ControlsButton = uibutton(app.UIFigure, 'push');
            app.ControlsButton.Enable = 'off';
            app.ControlsButton.Text = 'Controls';
            app.FiltersButton = uibutton(app.UIFigure, 'push');
            app.FiltersButton.Enable = 'off';
            app.FiltersButton.Text = 'Filters';
            app.ModesButton = uibutton(app.UIFigure, 'push');
            app.ModesButton.Enable = 'off';
            app.ModesButton.Text = 'Modes';
            app.InvertedPendulumButton = uibutton(app.UIFigure, 'push');
            app.InvertedPendulumButton.Enable = 'off';
            app.InvertedPendulumButton.Text = {'Inverted'; 'Pendulum'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_Tower002

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end