classdef SDnV_lab_GUI_controls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        HorizontalPendulumButton  matlab.ui.control.ToggleButton
        InvertedPendulumButton    matlab.ui.control.ToggleButton
        HangingPendulumButton     matlab.ui.control.ToggleButton
        Kp_input                  matlab.ui.control.TextArea
        Kd_input                  matlab.ui.control.TextArea
        Ki_input                  matlab.ui.control.TextArea
        MotorControllerAngleErrorAngularVelocityErrorLabel  matlab.ui.control.Label
        kdLabel                   matlab.ui.control.Label
        kpLabel                   matlab.ui.control.Label
        kiLabel                   matlab.ui.control.Label
        MotorControllerAngleErrorAngularVelocityErrorLabel_2  matlab.ui.control.Label
        SetHangingAngleButton     matlab.ui.control.Button
        TextArea2                 matlab.ui.control.TextArea
        Lamp                      matlab.ui.control.Lamp
        ConnecttoArduinoButton    matlab.ui.control.Button
        StopReadingButton         matlab.ui.control.Button
        StartReadingButton        matlab.ui.control.Button
        SystemDynamicsandVibrations__Controls____Label  matlab.ui.control.Label
        FilenameTextArea          matlab.ui.control.TextArea
        FilenameTextAreaLabel     matlab.ui.control.Label
        ExportDataButton          matlab.ui.control.Button
        UIAxesEncoder             matlab.ui.control.UIAxes
    end

    % Written be William Monroe Hochstedler
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
        iCOM
        TrialPeriod
        HangingAngleEnc
        goalAngle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          while isempty(app.sp) == true
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [1 0 0];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        app.Lamp.Color = [0 1 0];
                        app.ConnecttoArduinoButton.Enable = 'off';
                        app.SetHangingAngleButton.Enable = 'on';
                    end
                end
                if app.Lamp.Color ~= [0 1 0] 
                    app.ConnecttoArduinoButton.Text = 'Try Again';
                end
            end  
          end
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [1 0 0];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        app.Lamp.Color = [0 1 0];
                        app.ConnecttoArduinoButton.Enable = 'off';
                        app.SetHangingAngleButton.Enable = 'on';
                    end
                end
                if app.Lamp.Color ~= [0 1 0] 
                    app.ConnecttoArduinoButton.Text = 'Try Again';
                end
            end  
        end

        % Button pushed function: SetHangingAngleButton
        function SetHangingAngleButtonPushed(app, event)
            writeline(app.sp,"HangAng")
            TtempText = readline(app.sp);
            TtempText = splitlines(TtempText);            
            app.TextArea2.Value = TtempText(1); 
            if (TtempText(1) == "amHereHA")
                app.StopReadingButton.Enable = 'on';
                app.StartReadingButton.Enable = 'on';
                app.ButtonGroup.Enable = 'on';            
                app.SetHangingAngleButton.Enable = 'off';
                app.HangingAngleEnc = str2double(readline(app.sp));
            else
                app.TextArea2.Value = "error";
            end                      
                writeline(app.sp,"amHereHangP")
                TtempText = readline(app.sp); 
                TtempText = splitlines(TtempText);  
                if (TtempText(1) == "amHereHangP")                   
                    app.HangingPendulumButton.BackgroundColor = [0.7 0.9 0.5]; 
                    app.HorizontalPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.InvertedPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.TrialPeriod = 30;
                    app.goalAngle = 0;
                else
                    app.TextArea2.Value = "error";
                end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
           
            
            if (app.HangingPendulumButton.Value == true)
                writeline(app.sp,"amHereHangP")
                TtempText = readline(app.sp); 
                TtempText = splitlines(TtempText);  
                if (TtempText(1) == "amHereHangP")                   
                    app.HangingPendulumButton.BackgroundColor = [0.7 0.9 0.5]; 
                    app.HorizontalPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.InvertedPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.TrialPeriod = 30;
                    app.goalAngle = 0;
                else
                    app.TextArea2.Value = "error";
                end
            
            
           elseif (app.InvertedPendulumButton.Value == true)
                writeline(app.sp,"InvertPend")
                TtempText = readline(app.sp); 
                TtempText = splitlines(TtempText);  
                if (TtempText(1) == "amHereIP")               
                    app.HangingPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.HorizontalPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.InvertedPendulumButton.BackgroundColor = [0.7 0.9 0.5];
                    app.TrialPeriod = 30;
                    app.goalAngle = -180;
                else
                    app.TextArea2.Value = "error";
                end   
                
           elseif (app.HorizontalPendulumButton.Value == true)
                writeline(app.sp,"HorzPend")
                TtempText = readline(app.sp); 
                TtempText = splitlines(TtempText);  
                if (TtempText(1) == "amHereHP")               
                    app.HangingPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.HorizontalPendulumButton.BackgroundColor = [0.7 0.9 0.5];
                    app.InvertedPendulumButton.BackgroundColor = [0.9 0.7 0.7];
                    app.TrialPeriod = 2.5;
                    app.goalAngle = -90;
                else
                    app.TextArea2.Value = "error";
                end 
            end
           
            app.TextArea2.Value = TtempText(1);  
        end

        % Button pushed function: StartReadingButton
        function StartReadingButtonPushed(app, event)

            % disable inputs until after recording stopped
              app.StartReadingButton.Enable = 'off';
              app.Kp_input.Editable = 'off';
              app.Kd_input.Editable = 'off';
              app.Ki_input.Editable = 'off';
              app.ButtonGroup.Enable = 'off';
              app.FilenameTextArea.Enable = 'off';
              app.ExportDataButton.Enable = 'off';
              
            % tell the arduino the kp and kd,
            % but first check if they are any good
              kp = str2double(app.Kp_input.Value);
              kd = str2double(app.Kd_input.Value);
              ki = str2double(app.Ki_input.Value);
              
            % no input error
              if num2str(kp) == "NaN"  || num2str(kd) == "NaN" || num2str(ki) == "NaN"
                  app.TextArea2.Value = {'-missing-','Gain-Error'};
                  app.StartReadingButton.Enable = 'on';
                  app.Kp_input.Editable = 'on';
                  app.Kd_input.Editable = 'on';
                  app.Ki_input.Editable = 'on';
                  app.ButtonGroup.Enable = 'on';
                  app.FilenameTextArea.Enable = 'on';
                  app.ExportDataButton.Enable = 'on';
                  return
              end
              
            % constrain gains error
              kpBAD=min(max(kp,0),40);
              kdBAD=min(max(kd,0),40);
              kiBAD=min(max(ki,0),5);
              if kpBAD ~= kp || kdBAD ~= kd || kiBAD ~= ki
                  app.TextArea2.Value ={'-Out of Bounds-','Gain-Error'};
                  app.StartReadingButton.Enable = 'on';
                  app.Kp_input.Editable = 'on';
                  app.Kd_input.Editable = 'on';
                  app.Ki_input.Editable = 'on';
                  app.ButtonGroup.Enable = 'on';
                  app.FilenameTextArea.Enable = 'on';
                  app.ExportDataButton.Enable = 'on';
                  return
              end 


%               if kp < 0  || kd < 0
%                   app.TextArea2.Value = {'Gain Error','negative'};
%                   return
%               end
              
            % tell arduino to start recording
              writeline(app.sp,"start")
              %pause(0.1)
              writeline(app.sp,num2str(kp))
              writeline(app.sp,num2str(kd))
              writeline(app.sp,num2str(ki))
              %pause(1)
              %flush(app.sp);
               TtempText = readline(app.sp); 
               TtempText = splitlines(TtempText);
%               app.TextArea2.Value = TtempText;

            % initialize/clear OverTimer
              OverTimer = tic;
              
            % loop that collects data from serial port
              while (toc(OverTimer) < app.TrialPeriod) 
                  app.TextArea2.Value = TtempText;
                  pause(0.2)
                  app.TextArea2.Value = ' ';
                  pause(0.2)
              end
            % if time lapses, run stop read function
              StopReadingButtonPushed(app, event) 
        end

        % Button pushed function: StopReadingButton
        function StopReadingButtonPushed(app, event)

            % tell arduino to stop recording
              writeline(app.sp,"stop")
              TtempText = readline(app.sp); 
              TtempText = splitlines(TtempText);
              app.TextArea2.Value = TtempText;
              
            % initialize/clear datasets and OverTimer
              temptime = 0;
              app.Acceleration = 0;
              app.Time = 0;
              
            % receive data
            for ii = 1:250
                dataStr = readline(app.sp);  
              % convert incoming string into double
                dataNum = str2double(dataStr);   
              % now record time as the data sets are sent seporately
                temptime = temptime + 1/10;
                app.Time = [app.Time; temptime];
                app.Acceleration = [app.Acceleration; dataNum];
            end
              
              
            if isempty(app.Acceleration) == 1
                app.TextArea2.Value = {'No Data','Collected'};
                return
            end
            % plot the data
              app.Acceleration = ...
                  app.Acceleration(1:find(app.Acceleration,1,'last'));
              app.Acceleration = (app.Acceleration -...
                  app.HangingAngleEnc)*360/400;
              
              endingLine = (app.HangingAngleEnc+app.goalAngle)*ones(length(app.Acceleration)-1,1);
              plot(app.UIAxesEncoder,...
                  app.Time(2:length(app.Acceleration)), ...
                  endingLine,'k-')
              hold(app.UIAxesEncoder, 'on' )
              plot(app.UIAxesEncoder, ...
                  app.Time(2:length(app.Acceleration)), ...
                  app.Acceleration(2:end)*360/400,'b-')
              hold(app.UIAxesEncoder, 'off' )
              %flush(app.sp);
            % enable start button
              app.StartReadingButton.Enable = 'on';
              app.Kp_input.Editable = 'on';
              app.Kd_input.Editable = 'on';
              app.Ki_input.Editable = 'on';
              app.ButtonGroup.Enable = 'on';
              app.FilenameTextArea.Enable = 'on';
              app.ExportDataButton.Enable = 'on';
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
          % export data to text file
            TimeT = app.Time(1:length(app.Acceleration));
            AngleT = app.Acceleration(1:length(app.Acceleration));
            T = table(TimeT,AngleT);            
            filemeData = string(app.FilenameTextArea.Value);
            writetable(T,filemeData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesEncoder = uiaxes(app.UIFigure);
            xlabel(app.UIAxesEncoder, 'Time [s]')
            ylabel(app.UIAxesEncoder, 'Angle [°]')
            zlabel(app.UIAxesEncoder, 'Z')
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Enable = 'off';
            app.FilenameTextAreaLabel.Text = 'Filename:';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Enable = 'off';
            app.SystemDynamicsandVibrations__Controls____Label = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrations__Controls____Label.Text = {'System Dynamics and Vibrations'; ':\\__Controls__:_|_:'};
            app.StartReadingButton = uibutton(app.UIFigure, 'push');
            app.StartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StartReadingButtonPushed, true);
            app.StartReadingButton.Enable = 'off';
            app.StartReadingButton.Text = 'Start Reading';
            app.StopReadingButton = uibutton(app.UIFigure, 'push');
            app.StopReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReadingButtonPushed, true);
            app.StopReadingButton.Enable = 'off';
            app.StopReadingButton.Text = 'Stop Reading';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Editable = 'off';
            app.SetHangingAngleButton = uibutton(app.UIFigure, 'push');
            app.SetHangingAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetHangingAngleButtonPushed, true);
            app.SetHangingAngleButton.Enable = 'off';
            app.SetHangingAngleButton.Text = 'Set Hanging Angle';
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2 = uilabel(app.UIFigure);
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Text = '';
            app.kiLabel = uilabel(app.UIFigure);
            app.kiLabel.Text = {'ki'; ''; ''; ' (0 - 5) '};
            app.kpLabel = uilabel(app.UIFigure);
            app.kpLabel.Text = {'kp'; ''; ''; ' (0 - 40) '};
            app.kdLabel = uilabel(app.UIFigure);
            app.kdLabel.Text = {'kd'; ''; ''; ' (0 - 40) '};
            app.MotorControllerAngleErrorAngularVelocityErrorLabel = uilabel(app.UIFigure);
            app.MotorControllerAngleErrorAngularVelocityErrorLabel.Text = '   u(t) = –            *AngleError –            *d(AngularError)/dt –            *Integral(AngleError)';
            app.Ki_input = uitextarea(app.UIFigure);
            app.Ki_input.Value = {'0'};
            app.Kd_input = uitextarea(app.UIFigure);
            app.Kd_input.Value = {'0'};
            app.Kp_input = uitextarea(app.UIFigure);
            app.Kp_input.Value = {'0'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Enable = 'off';
            app.HangingPendulumButton = uitogglebutton(app.ButtonGroup);
            app.HangingPendulumButton.Text = 'Hanging Pendulum';
            app.HangingPendulumButton.Value = true;
            app.InvertedPendulumButton = uitogglebutton(app.ButtonGroup);
            app.InvertedPendulumButton.Text = 'Inverted Pendulum';
            app.HorizontalPendulumButton = uitogglebutton(app.ButtonGroup);
            app.HorizontalPendulumButton.Text = 'Horizontal Pendulum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_controls

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end