classdef SDnV_lab_GUI_mode_shape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        motorbuttononoffButton        matlab.ui.control.Button
        SetInputFrequencySliderLabel  matlab.ui.control.Label
        SetInputFrequencySlider       matlab.ui.control.Slider
        MotorControllerAngleErrorAngularVelocityErrorLabel_2  matlab.ui.control.Label
        TextArea2                     matlab.ui.control.TextArea
        Lamp                          matlab.ui.control.Lamp
        ConnecttoArduinoButton        matlab.ui.control.Button
        StopReadingButton             matlab.ui.control.Button
        StartReadingButton            matlab.ui.control.Button
        SystemDynamicsandVibrations___ModeShapes____________Label  matlab.ui.control.Label
        FilenameTextArea              matlab.ui.control.TextArea
        FilenameTextAreaLabel         matlab.ui.control.Label
        ExportDataButton              matlab.ui.control.Button
        UIAxesEncoder                 matlab.ui.control.UIAxes
    end

    % Written be William Monroe Hochstedler
    properties (Access = private)
        Acceleration % Description
        Velocity % Description
        Position % Description
        Time % Description
        sp % serial port object
        iCOM
        TrialPeriod
        HangingAngleEnc
        goalAngle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          while isempty(app.sp) == true
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [1 0 0];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        app.Lamp.Color = [0 1 0];
                        app.ConnecttoArduinoButton.Enable = 'off';
                       
                    end
                end
                if app.Lamp.Color ~= [0 1 0] 
                    app.ConnecttoArduinoButton.Text = 'Try Again';
                end
            end  
          end
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            if (isempty(app.iCOM)==1)
                app.iCOM = 0;
            end
            if (isempty(app.sp)==1)
                app.Lamp.Color = [1 0 0];
                spl = serialportlist;
                % for i = 1:length(spl)
                app.iCOM = app.iCOM + 1;
                if (app.iCOM > length(spl))
                    app.iCOM = 1;
                end
                serialObjectTemp = serialport(spl(app.iCOM), 115200);
                configureTerminator(serialObjectTemp,"LF");
                writeline(serialObjectTemp,"serialConnectNow")
                pause(0.5)
                app.TextArea2.Value = spl(app.iCOM);
                TtempText = readline(serialObjectTemp);
                app.TextArea2.Value = TtempText;                
                % if serial available and reads "amHere"
                if (class(TtempText) == "string")
                    TtempText = splitlines(TtempText);
                    if (TtempText(1) == "amHere")
                        clear serialObjectTemp; 
                        app.sp = serialport(spl(app.iCOM), 115200);
                        app.ConnecttoArduinoButton.Text = 'Connected';
                        app.Lamp.Color = [0 1 0];
                        app.ConnecttoArduinoButton.Enable = 'off';
                        
                    end
                end
                if app.Lamp.Color ~= [0 1 0] 
                    app.ConnecttoArduinoButton.Text = 'Try Again';
                end
            end  
        end

        % Button pushed function: StartReadingButton
        function StartReadingButtonPushed(app, event)

            % disable inputs until after recording stopped
              app.StartReadingButton.Enable = 'off';
              app.Kp_input.Editable = 'off';
              app.Kd_input.Editable = 'off';
              app.Ki_input.Editable = 'off';
              app.ButtonGroup.Enable = 'off';
              app.FilenameTextArea.Enable = 'off';
              app.ExportDataButton.Enable = 'off';
              
            % tell the arduino the kp and kd,
            % but first check if they are any good
              kp = str2double(app.Kp_input.Value);
              kd = str2double(app.Kd_input.Value);
              ki = str2double(app.Ki_input.Value);
              
            % no input error
              if num2str(kp) == "NaN"  || num2str(kd) == "NaN" || num2str(ki) == "NaN"
                  app.TextArea2.Value = {'-missing-','Gain-Error'};
                  app.StartReadingButton.Enable = 'on';
                  app.Kp_input.Editable = 'on';
                  app.Kd_input.Editable = 'on';
                  app.Ki_input.Editable = 'on';
                  app.ButtonGroup.Enable = 'on';
                  app.FilenameTextArea.Enable = 'on';
                  app.ExportDataButton.Enable = 'on';
                  return
              end
              
            % constrain gains error
              kpBAD=min(max(kp,0),40);
              kdBAD=min(max(kd,0),40);
              kiBAD=min(max(ki,0),5);
              if kpBAD ~= kp || kdBAD ~= kd || kiBAD ~= ki
                  app.TextArea2.Value ={'-Out of Bounds-','Gain-Error'};
                  app.StartReadingButton.Enable = 'on';
                  app.Kp_input.Editable = 'on';
                  app.Kd_input.Editable = 'on';
                  app.Ki_input.Editable = 'on';
                  app.ButtonGroup.Enable = 'on';
                  app.FilenameTextArea.Enable = 'on';
                  app.ExportDataButton.Enable = 'on';
                  return
              end 


%               if kp < 0  || kd < 0
%                   app.TextArea2.Value = {'Gain Error','negative'};
%                   return
%               end
              
            % tell arduino to start recording
              writeline(app.sp,"start")
              %pause(0.1)
%               writeline(app.sp,num2str(kp))
%               writeline(app.sp,num2str(kd))
%               writeline(app.sp,num2str(ki))
              %pause(1)
              %flush(app.sp);
               TtempText = readline(app.sp); 
               TtempText = splitlines(TtempText);
%               app.TextArea2.Value = TtempText;

            % initialize/clear OverTimer
              OverTimer = tic;
              
            % loop that collects data from serial port
              while (toc(OverTimer) < app.TrialPeriod) 
                  app.TextArea2.Value = TtempText;
                  pause(0.2)
                  app.TextArea2.Value = ' ';
                  pause(0.2)
              end
            % if time lapses, run stop read function
              StopReadingButtonPushed(app, event) 
        end

        % Button pushed function: StopReadingButton
        function StopReadingButtonPushed(app, event)

            % tell arduino to stop recording
              writeline(app.sp,"stop")
              TtempText = readline(app.sp); 
              TtempText = splitlines(TtempText);
              app.TextArea2.Value = TtempText;
              
            % initialize/clear datasets and OverTimer
              temptime = 0;
              app.Acceleration = 0;
              app.Time = 0;
              
            % receive data
            for ii = 1:250
                dataStr = readline(app.sp);  
              % convert incoming string into double
                dataNum = str2double(dataStr);   
              % now record time as the data sets are sent seporately
                temptime = temptime + 1/10;
                app.Time = [app.Time; temptime];
                app.Acceleration = [app.Acceleration; dataNum];
            end
              
              
            if isempty(app.Acceleration) == 1
                app.TextArea2.Value = {'No Data','Collected'};
                return
            end
            % plot the data
              app.Acceleration = ...
                  app.Acceleration(1:find(app.Acceleration,1,'last'));
              app.Acceleration = (app.Acceleration -...
                  app.HangingAngleEnc)*360/400;
              
              endingLine = (app.HangingAngleEnc+app.goalAngle)*ones(length(app.Acceleration)-1,1);
              plot(app.UIAxesEncoder,...
                  app.Time(2:length(app.Acceleration)), ...
                  endingLine,'k-')
              hold(app.UIAxesEncoder, 'on' )
              plot(app.UIAxesEncoder, ...
                  app.Time(2:length(app.Acceleration)), ...
                  app.Acceleration(2:end)*360/400,'b-')
              hold(app.UIAxesEncoder, 'off' )
              %flush(app.sp);
            % enable start button
              app.StartReadingButton.Enable = 'on';
              app.Kp_input.Editable = 'on';
              app.Kd_input.Editable = 'on';
              app.Ki_input.Editable = 'on';
              app.ButtonGroup.Enable = 'on';
              app.FilenameTextArea.Enable = 'on';
              app.ExportDataButton.Enable = 'on';
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
          % export data to text file
            TimeT = app.Time(1:length(app.Acceleration));
            AngleT = app.Acceleration(1:length(app.Acceleration));
            T = table(TimeT,AngleT);            
            filemeData = string(app.FilenameTextArea.Value);
            writetable(T,filemeData);
        end

        % Button pushed function: motorbuttononoffButton
        function motorbuttononoffButtonPushed(app, event)
              writeline(app.sp,"MOO")
              writeline(app.sp,num2str(app.SetInputFrequencySlider.Value))
              %pause(1)
              %flush(app.sp);
               TtempText = readline(app.sp); 
               TtempText = splitlines(TtempText);
%               app.TextArea2.Value = TtempText;
               if (TtempText(1) == "amMOOFF")
            app.motorbuttononoffButton.BackgroundColor = [0.8314 0.7294 0.9216];
            app.motorbuttononoffButton.FontColor = [0.6353 0.0784 0.1843];
            app.motorbuttononoffButton.Text = {'MOTOR'; 'OFF'};
               end
               if (TtempText(1) == "amMOON")
            app.motorbuttononoffButton.BackgroundColor = [0.89,0.94,0.83];
            app.motorbuttononoffButton.FontColor = [0.47,0.67,0.19];
            app.motorbuttononoffButton.Text = {'MOTOR'; 'ON'};
               end

        end

        % Value changed function: SetInputFrequencySlider
        function SetInputFrequencySliderValueChanged(app, event)
            value = app.SetInputFrequencySlider.Value;
            app.TextArea2.Value = num2str(value);
            writeline(app.sp,"ChangeFrequency")
            writeline(app.sp,num2str(value))     
            %TtempText = readline(app.sp); 
            %TtempText = splitlines(TtempText);
            %app.TextArea2.Value = TtempText;
            % initialize/clear OverTimer
             % app.OverTimer = tic;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesEncoder = uiaxes(app.UIFigure);
            xlabel(app.UIAxesEncoder, 'Time [s]')
            ylabel(app.UIAxesEncoder, 'Angle [°]')
            zlabel(app.UIAxesEncoder, 'Z')
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Enable = 'off';
            app.FilenameTextAreaLabel.Text = 'Filename:';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Enable = 'off';
            app.SystemDynamicsandVibrations___ModeShapes____________Label = uilabel(app.UIFigure);
            app.SystemDynamicsandVibrations___ModeShapes____________Label.Text = {'\___\   System Dynamics and Vibrations   '; '     /___/               Mode Shapes                              '; '        ___:___\___\___:                                                                            '};
            app.StartReadingButton = uibutton(app.UIFigure, 'push');
            app.StartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StartReadingButtonPushed, true);
            app.StartReadingButton.Enable = 'off';
            app.StartReadingButton.Text = 'Start Reading';
            app.StopReadingButton = uibutton(app.UIFigure, 'push');
            app.StopReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReadingButtonPushed, true);
            app.StopReadingButton.Enable = 'off';
            app.StopReadingButton.Text = 'Stop Reading';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Editable = 'off';
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2 = uilabel(app.UIFigure);
            app.MotorControllerAngleErrorAngularVelocityErrorLabel_2.Text = '';
            app.SetInputFrequencySlider = uislider(app.UIFigure);
            app.SetInputFrequencySlider.Limits = [1 7];
            app.SetInputFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @SetInputFrequencySliderValueChanged, true);
            app.SetInputFrequencySlider.Value = 1;
            app.SetInputFrequencySliderLabel = uilabel(app.UIFigure);
            app.SetInputFrequencySliderLabel.Text = 'Set Input Frequency';
            app.motorbuttononoffButton = uibutton(app.UIFigure, 'push');
            app.motorbuttononoffButton.ButtonPushedFcn = createCallbackFcn(app, @motorbuttononoffButtonPushed, true);
            app.motorbuttononoffButton.Text = {'motor button'; 'on/off'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDnV_lab_GUI_mode_shape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end